classdef UAS_Wil_Midnight_Crazy_Baru_Finally < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ImputationPanel                 matlab.ui.container.Panel
        Spinner                         matlab.ui.control.Spinner
        SpinnerLabel                    matlab.ui.control.Label
        ImputationMethodDropDown        matlab.ui.control.DropDown
        ImputationMethodDropDownLabel   matlab.ui.control.Label
        PerformImputationButton         matlab.ui.control.Button
        DataSelectionPanel              matlab.ui.container.Panel
        CurrentFileEditField            matlab.ui.control.EditField
        CurrentFileEditFieldLabel       matlab.ui.control.Label
        ImportDataButton                matlab.ui.control.Button
        ColumntobeReviewedSpinnerLabel  matlab.ui.control.Label
        ColumntobeReviewedSpinner       matlab.ui.control.Spinner
        UITable                         matlab.ui.control.Table
        ARIMAForecastPanel              matlab.ui.container.Panel
        NumberofTimePeriodstoForecastEditField  matlab.ui.control.NumericEditField
        NumberofTimePeriodstoForecastEditFieldLabel  matlab.ui.control.Label
        TrainingDataAmountDecimalEditField  matlab.ui.control.NumericEditField
        TrainingDataAmountDecimalEditFieldLabel  matlab.ui.control.Label
        BootstrapIterationNumberEditField  matlab.ui.control.NumericEditField
        BootstrapIterationNumberEditFieldLabel  matlab.ui.control.Label
        BootstrapsBlockLengthEditField  matlab.ui.control.NumericEditField
        BootstrapsBlockLengthEditFieldLabel  matlab.ui.control.Label
        MAParameterEditField            matlab.ui.control.NumericEditField
        MAParameterEditFieldLabel       matlab.ui.control.Label
        OrderofDifferencingEditField    matlab.ui.control.NumericEditField
        OrderofDifferencingEditFieldLabel  matlab.ui.control.Label
        ARParameterEditField            matlab.ui.control.NumericEditField
        ARParameterEditFieldLabel       matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        ErrorTableLabel                 matlab.ui.control.Label
        ForecastButton                  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        StationarityTestPanel           matlab.ui.container.Panel
        StationaryEditField             matlab.ui.control.EditField
        StationaryEditFieldLabel        matlab.ui.control.Label
        ADFTestButton                   matlab.ui.control.Button
        OrderofDifferencingSpinner      matlab.ui.control.Spinner
        OrderofDifferencingSpinnerLabel  matlab.ui.control.Label
        SignificanceLevelDecimalSpinner  matlab.ui.control.Spinner
        SignificanceLevelDecimalSpinnerLabel  matlab.ui.control.Label
        DisplayACFandPACFPlotsButton    matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    properties (Access = public)
        % Properti existing
        filePath                        % Tambahkan ini untuk menyimpan filePath
        test_data
        forecasted_test_data
        data_X
        data_Y
        dataOriginal 
        dataNumeric 
        dataNumericOriginal
        dataAfterImputation
        error_MAE_ARIMA
        error_MSE_ARIMA
        error_MAE_ARIMA_bootstrap
        error_MSE_ARIMA_bootstrap
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed2(app, event)
            [filename, path] = uigetfile();
            if filename == 0
                % User cancelled the operation
                disp('User cancelled the file selection.');
            else
                % Set the filename in the edit field and save the full path
                app.CurrentFileEditField.Value = filename;
                app.filePath = fullfile(path, filename); % Save full path
                disp(['Selected file: ', app.filePath]); % Optional: display selected file path

                Source = readtable(app.filePath); % Import data from selected file

                %Extracting all numeric columns except the first one (assumed non-numeric)
                numericColumns = varfun(@isnumeric, Source, 'OutputFormat', 'uniform');
                numericColumns(1) = false; % Assuming the first column is not numeric
                app.dataNumeric = table2array(Source(:, numericColumns)); % Extract numeric data
        
                % Storing a copy of the original full data and numeric data
                app.dataOriginal = Source; % Store the whole original data
                app.dataNumericOriginal = app.dataNumeric; % Store the original numeric data
        
                % Updating the UITable to show only numeric data
                app.UITable.Data = Source;
                app.UITable.ColumnName = Source.Properties.VariableNames;

            end
        end

        % Button pushed function: PerformImputationButton
        function PerformImputationButtonPushed(app, event)
            % Nested function for imputation
            function table = GeneralImputation(table, method)
                numeric_column_names = table.Properties.VariableNames(2:end); % Skip the first non-numeric column
                for col = 1:length(numeric_column_names)
                    column_data = table2array(table(:, numeric_column_names{col}));
                    last_valid_index = find(~isnan(column_data), 1, 'last');
                    
                    if isempty(last_valid_index)
                        continue; % Skip columns with all NaN values
                    end
                    
                    missing_indices = find(isnan(column_data(1:last_valid_index)));
                    
                    non_missing_indices = find(~isnan(column_data(1:last_valid_index)));
                    non_missing_values = column_data(non_missing_indices);


                    if isempty(missing_indices)
                        continue; % Skip if no missing data in the current range
                    end
                    
                    % Determine the imputation value based on the specified method
                    if strcmp(method, 'mean')
                        imputation_value = mean(column_data(~isnan(column_data(1:last_valid_index))));
                        column_data(missing_indices) = imputation_value;
                    elseif strcmp(method, 'median')
                        imputation_value = median(column_data(~isnan(column_data(1:last_valid_index))));
                        column_data(missing_indices) = imputation_value;
                    elseif strcmp(method, 'knn')
                        k = app.Spinner.Value; %nilai K diambil paling optimal berdasarkan literatur

                        %Proses KNN Imputation
                        for i = 1:length(missing_indices)
                            distances = abs(non_missing_indices - missing_indices(i));
                            [~, sortedIdx] = sort(distances);
                            nearestNeighborsIdx = non_missing_indices(sortedIdx(1:k));
                            column_data(missing_indices(i)) = mean(column_data(nearestNeighborsIdx));
                        end
                    elseif strcmp(method, 'linear interpolation')
                        if ~isempty(missing_indices)
                            % Mendapatkan indeks dari nilai yang tidak hilang
                            valid_indices = find(~isnan(column_data));
                            
                            % Mendapatkan nilai yang tidak hilang
                            valid_data = column_data(valid_indices);
                            
                            % Menggunakan interpolasi linear
                            column_data(missing_indices) = interp1(valid_indices, valid_data, missing_indices, 'linear');
                        end
                    elseif strcmp(method, 'polynomial interpolation')
                        % penentuan order untuk imputation
                        order = app.Spinner.Value;
                    
                        % Interpolasi
                        p = polyfit(non_missing_indices, non_missing_values, order);
                        interpolated_values = polyval(p, missing_indices);
                        
                        % Replace missing values with interpolated values
                        column_data(missing_indices) = interpolated_values;
                    else
                        error('Unsupported imputation method.');
                    end
                    
                    % Apply the imputation value to missing indices
                    table.(numeric_column_names{col}) = column_data;
                end
            end
        
            % Use a copy of the original data for imputation to preserve the original state
            dataTable = app.dataOriginal;
            
            % Determine the imputation method from the dropdown and apply it
            selectedMethod = app.ImputationMethodDropDown.Value;
            if strcmp(selectedMethod, 'Mean')
                updatedTable = GeneralImputation(dataTable, 'mean');
            elseif strcmp(selectedMethod, 'Median')
                updatedTable = GeneralImputation(dataTable, 'median');
            elseif strcmp(selectedMethod, 'KNN')
                updatedTable = GeneralImputation(dataTable, 'knn');
            elseif strcmp(selectedMethod, 'Linear Interpolation')
                updatedTable = GeneralImputation(dataTable, 'linear interpolation');
            elseif strcmp(selectedMethod, 'Polynomial Interpolation')
                updatedTable = GeneralImputation(dataTable, 'polynomial interpolation');
            else
                disp('Imputation method is unknown.');
                return;
            end
        
            % Store the imputed data for further use
            app.dataAfterImputation = updatedTable;
            % Check if data is stored correctly
            disp('Data stored in dataAfterImputation property:');
            summary(app.dataAfterImputation);


            % Update the UI table by refreshing its data reference
            app.UITable.Data = [];
            drawnow; % Forces MATLAB to update the UI immediately
            app.UITable.Data = updatedTable;
        end

        % Button pushed function: ADFTestButton
        function ADFTestButtonPushed(app, event)
            % Mengambil kolom spesifik dari data imputed untuk uji ADF
            columnToTest = app.ColumntobeReviewedSpinner.Value;
            
            x = app.OrderofDifferencingSpinner.Value;

            % Directly check if dataAfterImputation is empty
            if ~isempty(app.dataAfterImputation)
                disp('Using dataAfterImputation');
                dataToUse = app.dataAfterImputation;
            else
                disp('Using dataOriginal because dataAfterImputation is empty or not present');
                dataToUse = app.dataOriginal;
            end

            % Mengambil data kolom spesifik untuk uji ADF
            data_ADF_original = dataToUse{:, columnToTest}; % Periksa bahwa pengindeksan kolom ini benar
      
        
            % Menggunakan data original sebagai dasar jika diferensiasi 0
            if x == 0
                data_ADF = data_ADF_original;
            else
                data_ADF = data_ADF_original;
                for i = 1:x
                    data_ADF = diff(data_ADF);
                end
            end

            % Melakukan uji ADF
            [hypothesisTest, pValue, adfStat, criticalValues] = adftest(data_ADF);
            if pValue < app.SignificanceLevelDecimalSpinner.Value
                S = 'Yes';
            else
                S = 'No';
            end
            app.StationaryEditField.Value = S
        end

        % Button pushed function: DisplayACFandPACFPlotsButton
        function DisplayACFandPACFPlotsButtonPushed(app, event)
            % Mengambil kolom spesifik dari data imputed untuk uji ADF
            columnToTest = app.ColumntobeReviewedSpinner.Value;
            
            x = app.OrderofDifferencingSpinner.Value;
            
            % Directly check if dataAfterImputation is empty
            if ~isempty(app.dataAfterImputation)
                disp('Using dataAfterImputation');
                dataToUse = app.dataAfterImputation;
            else
                disp('Using dataOriginal because dataAfterImputation is empty or not present');
                dataToUse = app.dataOriginal;
            end
        
            % Mengambil data kolom spesifik untuk uji ACF PACF
            data_ACF_original = dataToUse{:, columnToTest}; % Periksa bahwa pengindeksan kolom ini benar
            
            % Menggunakan data original sebagai dasar jika diferensiasi 0
            if x == 0
                data_ACF = data_ACF_original;
            else
                data_ACF = data_ACF_original;
                for i = 1:x
                    data_ACF = diff(data_ACF);
                end
            end
            
            %autocorr(app.UIAxes2, app.data_Y);
            
            % Calculate ACF and PACF
            [acfValues, acfLags] = autocorr(data_ACF, 'NumLags', 20);
            [pacfValues, pacfLags] = parcorr(data_ACF, 'NumLags', 20);
            numObservations = length(data_ACF);
            confidenceBound = 1.96 / sqrt(numObservations);

            % Plot ACF
            cla(app.UIAxes2); % Clear previous plot
            stem(app.UIAxes2, acfLags, acfValues, 'filled');
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, acfLags, confidenceBound * ones(size(acfLags)), '--r'); % Upper confidence bound
            plot(app.UIAxes2, acfLags, -confidenceBound * ones(size(acfLags)), '--r'); % Lower confidence bound
            grid(app.UIAxes2, 'on'); % Enable grid
            hold(app.UIAxes2, 'off');
            title(app.UIAxes2, 'Autocorrelation Function');
            xlabel(app.UIAxes2, 'Lag'); % Label for X-axis
            ylabel(app.UIAxes2, 'ACF'); % Label for Y-axis


            %parcorr(app.UIAxes3, app.data_Y);
            
            % Plot PACF
            cla(app.UIAxes3); % Clear previous plot
            stem(app.UIAxes3, pacfLags, pacfValues, 'filled');
            hold(app.UIAxes3, 'on');
            plot(app.UIAxes3, pacfLags, confidenceBound * ones(size(pacfLags)), '--r'); % Upper confidence bound
            plot(app.UIAxes3, pacfLags, -confidenceBound * ones(size(pacfLags)), '--r'); % Lower confidence bound
            grid(app.UIAxes3, 'on'); % Enable grid
            hold(app.UIAxes3, 'off');
            title(app.UIAxes3, 'Partial Autocorrelation Function');
            xlabel(app.UIAxes3, 'Lag'); % Label for X-axis
            ylabel(app.UIAxes3, 'PACF'); % Label for Y-axis
        end

        % Button pushed function: ForecastButton
        function ForecastButtonPushed(app, event)
            % Mengambil kolom spesifik dari data imputed untuk forecast
            columnToTest = app.ColumntobeReviewedSpinner.Value;
            
            % Directly check if dataAfterImputation is empty
            if ~isempty(app.dataAfterImputation)
                disp('Using dataAfterImputation');
                dataToUse = app.dataAfterImputation;
            else
                disp('Using dataOriginal because dataAfterImputation is empty or not present');
                dataToUse = app.dataOriginal;
            end

            % Mengambil data kolom spesifik untuk forecast
            app.data_X = dataToUse{:, 1};
            app.data_Y = dataToUse{:, columnToTest};

            data_X = app.data_X;
            data_Y = app.data_Y;

            disp(data_Y);

            % Trim trailing NaNs from data_Y (agar forecast dimulai setelah
            % data valid terakhir)
            lastValidIndex = find(~isnan(data_Y), 1, 'last');
            if ~isempty(lastValidIndex)
                data_Y = data_Y(1:lastValidIndex);
            else
                warning('No valid data in the selected column for forecasting.');
                return;
            end

            p = app.ARParameterEditField.Value; %Nilai AR
            d = app.OrderofDifferencingEditField.Value; %Nilai diferensiasi
            q = app.MAParameterEditField.Value; %Nilai MA
            t = app.NumberofTimePeriodstoForecastEditField.Value; %Jumlah hari forecast
            B = app.BootstrapsBlockLengthEditField.Value; 
            iterasi_bootstrap = app.BootstrapIterationNumberEditField.Value;

            %function ARIMA_Bootstrap
            function [AR_coeffs, MA_coeffs] = ARIMA_Bootstrap(p,d,q,data_Y)
                mdl = estimate(arima(p,d,q), data_Y);
                
                AR_coeffs = [];
                MA_coeffs = [];
                
                % Menyimpan koefisien pada persamaan time series
                if p>0
                    for i = 1:p
                        AR_coeffs = [AR_coeffs, mdl.AR{i}];
                    end
                end
                
                if q>0
                    for i = 1:q
                        MA_coeffs = [MA_coeffs, mdl.MA{i}];
                    end
                end
            end

            %main code
            %Split data for training and testing
            train_ratio = app.TrainingDataAmountDecimalEditField.Value;
            train_size = floor(train_ratio * length(data_Y));
            train_data = data_Y(1:train_size);
            test_data = data_Y(train_size+1:end);
            
            Mdl =  estimate(arima(p,d,q), train_data);
            
            n = length(train_data) - B +1; % Banyaknya Blok
            
            % Matrix untuk menyimpan koefisien setiap iterasi
            AR_coeffs_boot_iter_all = [];
            MA_coeffs_boot_iter_all = [];
            
            % Matrix yang menyimpan rata-rata koefisien dari bootstrap
            AR_coeffs_bootstrap_mean = [];
            MA_coeffs_bootstrap_mean = [];
            
            for i = 1:iterasi_bootstrap
                data_bootstrap = [];
                for j = 1:n
                    random = randi([1,n]);
                    data_bootstrap = [data_bootstrap,train_data(random:(random + B - 1))'];
                end
                [AR_coeffs_boot_iter_one, MA_coeffs_boot_iter_one] = ARIMA_Bootstrap(p,d,q,data_bootstrap');
                AR_coeffs_boot_iter_all = [AR_coeffs_boot_iter_all; AR_coeffs_boot_iter_one];
                MA_coeffs_boot_iter_all = [MA_coeffs_boot_iter_all; MA_coeffs_boot_iter_one];
            end
            
            for i = 1:p
                AR_coeffs_bootstrap_mean = [AR_coeffs_bootstrap_mean, mean(AR_coeffs_boot_iter_all(:,i))];
            end
            
            for i = 1:q
                MA_coeffs_bootstrap_mean = [MA_coeffs_bootstrap_mean, mean(MA_coeffs_boot_iter_all(:,i))];
            end

            sys_bootstrap = arima('AR', AR_coeffs_bootstrap_mean, 'MA', MA_coeffs_bootstrap_mean, 'Constant', Mdl.Constant, 'Variance', Mdl.Variance, 'Distribution', Mdl.Distribution);
            bootstrap_Mdl = estimate(sys_bootstrap,data_Y); %generate model yang mungkin berdasarkan arima yang diinginkan

            yf = forecast(Mdl,t,'Y0',data_Y);
            yf_bootstrap = forecast(bootstrap_Mdl,t,'Y0',data_Y); %forecast beberapa hari ke depan
            
            app.test_data = test_data;  % Simpan data uji
            app.forecasted_test_data = yf_bootstrap;  % Simpan hasil forecast

            plot(app.UIAxes,1:length(data_Y),data_Y,'m',length(data_Y):length(data_Y)+length(yf),[data_Y(end);yf],'g');
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,1:length(data_Y),data_Y,'b',length(data_Y):length(data_Y)+length(yf_bootstrap),[data_Y(end);yf_bootstrap],'r'); % Existing data plot
            lgd = legend(app.UIAxes,{'Forecasted ARIMA Bootstrap', 'Forecasted ARIMA', 'Measured'});

            % Mengatur ukuran font dari legend
            lgd.FontSize = 5;

            hold(app.UIAxes, 'off');
            title(app.UIAxes, "Forecast Result");
            xlabel(app.UIAxes, "Time Period");
            ylabel(app.UIAxes, "Y");

            % Menghitung Error
            actual = app.test_data; % Data aktual dari test data
            % Melakukan prediksi dengan model ARIMA
            yPred = forecast(Mdl, length(test_data),'Y0', train_data);
            yPred_Bootstrap = forecast(bootstrap_Mdl, length(test_data),'Y0', train_data);

            % Menghitung Mean Absolute Error (MAE) untuk ARIMA
            MAE_ARIMA = mean(abs(yPred - actual));
            % Menghitung Mean Squared Error (MSE) untuk ARIMA
            MSE_ARIMA = mean((yPred - actual).^2);
            % Menyimpan hasil error ARIMA ke properti
            app.error_MAE_ARIMA = MAE_ARIMA;
            app.error_MSE_ARIMA = MSE_ARIMA;

            % Menghitung Mean Absolute Error (MAE) untuk ARIMA dengan bootstrap
            MAE_ARIMA_bootstrap = mean(abs(yPred_Bootstrap - actual));
            % Menghitung Mean Squared Error (MSE) untuk ARIMA dengan bootstrap
            MSE_ARIMA_bootstrap = mean((yPred_Bootstrap - actual).^2);
            % Menyimpan hasil error ARIMA dengan bootstrap ke properti
            app.error_MAE_ARIMA_bootstrap = MAE_ARIMA_bootstrap;
            app.error_MSE_ARIMA_bootstrap = MSE_ARIMA_bootstrap;

            % Membuat tabel error untuk ARIMA
            errorTable_ARIMA = table({'MAE'; 'MSE'}, ...
                [app.error_MAE_ARIMA; app.error_MSE_ARIMA], ...
                'VariableNames', {'ErrorType', 'Value'});

            % Membuat tabel error untuk ARIMA dengan bootstrap
            errorTable_ARIMA_bootstrap = table({'MAE Bootstrap'; 'MSE Bootstrap'}, ...
                [app.error_MAE_ARIMA_bootstrap; app.error_MSE_ARIMA_bootstrap], ...
                'VariableNames', {'ErrorType', 'Value'});

            % Menyatukan tabel error dari kedua model
            combinedErrorTable = [errorTable_ARIMA; errorTable_ARIMA_bootstrap];

            % Memperbarui properti Data dari tabel yang dituju dengan tabel yang baru
            app.UITable2.Data = combinedErrorTable;

        end

        % Cell edit callback: UITable2
        function UITable2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Value changed function: ImputationMethodDropDown
        function ImputationMethodDropDownValueChanged(app, event)
        selectedMethod = app.ImputationMethodDropDown.Value;
        if strcmp(selectedMethod, 'Polynomial Interpolation')
            app.Spinner.Visible = 'on';
            app.SpinnerLabel.Text = 'Polynomial Order';
            app.SpinnerLabel.Visible = 'on';
            app.Spinner.Limits = [2, inf];
            app.Spinner.Value = 2;  % Set default value to 2
        elseif strcmp(selectedMethod, 'KNN')
            app.Spinner.Visible = 'on';
            app.SpinnerLabel.Text = 'Value of K';
            app.SpinnerLabel.Visible = 'on';
            app.Spinner.Limits = [1, inf];
            app.Spinner.Value = 1;  % Set default value to 1
        else
            app.Spinner.Visible = 'off';
            app.SpinnerLabel.Visible = 'off';
        end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StationarityTestPanel = uipanel(app.UIFigure);
            app.StationarityTestPanel.Title = 'Stationarity Test';
            app.UIAxes2 = uiaxes(app.StationarityTestPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.StationarityTestPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.DisplayACFandPACFPlotsButton = uibutton(app.StationarityTestPanel, 'push');
            app.DisplayACFandPACFPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayACFandPACFPlotsButtonPushed, true);
            app.DisplayACFandPACFPlotsButton.Text = 'Display ACF and PACF Plots';
            app.SignificanceLevelDecimalSpinnerLabel = uilabel(app.StationarityTestPanel);
            app.SignificanceLevelDecimalSpinnerLabel.Text = 'Significance Level (Decimal)';
            app.SignificanceLevelDecimalSpinner = uispinner(app.StationarityTestPanel);
            app.SignificanceLevelDecimalSpinner.Step = 0.01;
            app.SignificanceLevelDecimalSpinner.Limits = [0 1];
            app.SignificanceLevelDecimalSpinner.Value = 0.05;
            app.OrderofDifferencingSpinnerLabel = uilabel(app.StationarityTestPanel);
            app.OrderofDifferencingSpinnerLabel.Text = 'Order of Differencing';
            app.OrderofDifferencingSpinner = uispinner(app.StationarityTestPanel);
            app.OrderofDifferencingSpinner.Limits = [0 Inf];
            app.ADFTestButton = uibutton(app.StationarityTestPanel, 'push');
            app.ADFTestButton.ButtonPushedFcn = createCallbackFcn(app, @ADFTestButtonPushed, true);
            app.ADFTestButton.Text = 'ADF Test';
            app.StationaryEditFieldLabel = uilabel(app.StationarityTestPanel);
            app.StationaryEditFieldLabel.Text = 'Stationary';
            app.StationaryEditField = uieditfield(app.StationarityTestPanel, 'text');
            app.StationaryEditField.Editable = 'off';
            app.ARIMAForecastPanel = uipanel(app.UIFigure);
            app.ARIMAForecastPanel.Title = 'ARIMA Forecast';
            app.UIAxes = uiaxes(app.ARIMAForecastPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ForecastButton = uibutton(app.ARIMAForecastPanel, 'push');
            app.ForecastButton.ButtonPushedFcn = createCallbackFcn(app, @ForecastButtonPushed, true);
            app.ForecastButton.Text = 'Forecast';
            app.ErrorTableLabel = uilabel(app.ARIMAForecastPanel);
            app.ErrorTableLabel.Text = 'Error Table';
            app.UITable2 = uitable(app.ARIMAForecastPanel);
            app.UITable2.ColumnName = {'Error Type'; 'Value'};
            app.UITable2.RowName = {};
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit, true);
            app.ARParameterEditFieldLabel = uilabel(app.ARIMAForecastPanel);
            app.ARParameterEditFieldLabel.Text = 'AR Parameter';
            app.ARParameterEditField = uieditfield(app.ARIMAForecastPanel, 'numeric');
            app.OrderofDifferencingEditFieldLabel = uilabel(app.ARIMAForecastPanel);
            app.OrderofDifferencingEditFieldLabel.Text = 'Order of Differencing';
            app.OrderofDifferencingEditField = uieditfield(app.ARIMAForecastPanel, 'numeric');
            app.MAParameterEditFieldLabel = uilabel(app.ARIMAForecastPanel);
            app.MAParameterEditFieldLabel.Text = 'MA Parameter';
            app.MAParameterEditField = uieditfield(app.ARIMAForecastPanel, 'numeric');
            app.BootstrapsBlockLengthEditFieldLabel = uilabel(app.ARIMAForecastPanel);
            app.BootstrapsBlockLengthEditFieldLabel.Text = 'Bootstrap''s Block Length';
            app.BootstrapsBlockLengthEditField = uieditfield(app.ARIMAForecastPanel, 'numeric');
            app.BootstrapIterationNumberEditFieldLabel = uilabel(app.ARIMAForecastPanel);
            app.BootstrapIterationNumberEditFieldLabel.Text = 'Bootstrap Iteration Number';
            app.BootstrapIterationNumberEditField = uieditfield(app.ARIMAForecastPanel, 'numeric');
            app.TrainingDataAmountDecimalEditFieldLabel = uilabel(app.ARIMAForecastPanel);
            app.TrainingDataAmountDecimalEditFieldLabel.Text = 'Training Data Amount (Decimal)';
            app.TrainingDataAmountDecimalEditField = uieditfield(app.ARIMAForecastPanel, 'numeric');
            app.NumberofTimePeriodstoForecastEditFieldLabel = uilabel(app.ARIMAForecastPanel);
            app.NumberofTimePeriodstoForecastEditFieldLabel.Text = 'Number of Time Periods to Forecast';
            app.NumberofTimePeriodstoForecastEditField = uieditfield(app.ARIMAForecastPanel, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ColumntobeReviewedSpinner = uispinner(app.UIFigure);
            app.ColumntobeReviewedSpinner.Limits = [2 Inf];
            app.ColumntobeReviewedSpinner.Value = 2;
            app.ColumntobeReviewedSpinnerLabel = uilabel(app.UIFigure);
            app.ColumntobeReviewedSpinnerLabel.Text = 'Column to be Reviewed';
            app.DataSelectionPanel = uipanel(app.UIFigure);
            app.DataSelectionPanel.Title = 'Data Selection';
            app.ImportDataButton = uibutton(app.DataSelectionPanel, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed2, true);
            app.ImportDataButton.Text = 'Import Data';
            app.CurrentFileEditFieldLabel = uilabel(app.DataSelectionPanel);
            app.CurrentFileEditFieldLabel.Text = 'Current File ';
            app.CurrentFileEditField = uieditfield(app.DataSelectionPanel, 'text');
            app.CurrentFileEditField.Editable = 'off';
            app.ImputationPanel = uipanel(app.UIFigure);
            app.ImputationPanel.Title = 'Imputation';
            app.PerformImputationButton = uibutton(app.ImputationPanel, 'push');
            app.PerformImputationButton.ButtonPushedFcn = createCallbackFcn(app, @PerformImputationButtonPushed, true);
            app.PerformImputationButton.Text = 'Perform Imputation';
            app.ImputationMethodDropDownLabel = uilabel(app.ImputationPanel);
            app.ImputationMethodDropDownLabel.Text = 'Imputation Method';
            app.ImputationMethodDropDown = uidropdown(app.ImputationPanel);
            app.ImputationMethodDropDown.Items = {'Mean', 'Median', 'KNN', 'Linear Interpolation', 'Polynomial Interpolation'};
            app.ImputationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ImputationMethodDropDownValueChanged, true);
            app.ImputationMethodDropDown.Value = 'Mean';
            app.SpinnerLabel = uilabel(app.ImputationPanel);
            app.SpinnerLabel.Visible = 'off';
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.ImputationPanel);
            app.Spinner.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UAS_Wil_Midnight_Crazy_Baru_Finally

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end